classdef TCApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        EndSessionButton             matlab.ui.control.Button
        RecordingSpikesLamp          matlab.ui.control.Lamp
        RecordingSpikesLabel         matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        CollectingBaselineLamp       matlab.ui.control.Lamp
        CollectingBaselineLampLabel  matlab.ui.control.Label
        GetBaselineButton            matlab.ui.control.Button
        UIAxes_mav_event             matlab.ui.control.UIAxes
        UIAxes_semg                  matlab.ui.control.UIAxes
        UIAxes_mav                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        BaselineData
        DataLogger
        Timer
        RecordSession = 1;
    end
    
    methods (Access = public)
        
        function TimerCallback(app, obj, event)
            app.CollectingBaselineLamp.Color='black';
            disp('ez');
            stop(app.Timer);
            app.BaselineData = app.DataLogger.getRecentEMG;
        end
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GetBaselineButton
        function GetBaselineButtonPushed(app, event)
            app.Timer = timer;
            app.Timer.StartDelay = 5;
            app.Timer.TimerFcn = @app.TimerCallback;
            start(app.Timer);

            app.CollectingBaselineLamp.Color = 'red';
        end

        % Button pushed function: EndSessionButton
        function EndSessionButtonPushed(app, event)
            app.RecordSession = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_mav = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_mav, 'Time [s]')
            ylabel(app.UIAxes_mav, 'MAV [V]')
            zlabel(app.UIAxes_mav, 'Z')
            app.UIAxes_mav.XLim = [0 5];
            app.UIAxes_mav.YLim = [0 1];
            app.UIAxes_semg = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_semg, 'Time [s]')
            ylabel(app.UIAxes_semg, 'sEMG [V]')
            zlabel(app.UIAxes_semg, 'Z')
            app.UIAxes_semg.XLim = [0 5];
            app.UIAxes_semg.YLim = [-2.75 2.75];
            app.UIAxes_mav_event = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_mav_event, 'Time [s]')
            ylabel(app.UIAxes_mav_event, 'MAV [V]')
            zlabel(app.UIAxes_mav_event, 'Z')
            app.UIAxes_mav_event.XLim = [0 3];
            app.UIAxes_mav_event.YLim = [0 1];
            app.GetBaselineButton = uibutton(app.UIFigure, 'push');
            app.GetBaselineButton.ButtonPushedFcn = createCallbackFcn(app, @GetBaselineButtonPushed, true);
            app.GetBaselineButton.Text = {'Get'; 'Baseline'};
            app.CollectingBaselineLampLabel = uilabel(app.UIFigure);
            app.CollectingBaselineLampLabel.Text = 'Collecting Baseline';
            app.CollectingBaselineLamp = uilamp(app.UIFigure);
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Initiation Time Constant = '; ''; 'Termination Time Constant = '};
            app.RecordingSpikesLabel = uilabel(app.UIFigure);
            app.RecordingSpikesLabel.Text = 'Recording Spikes  ';
            app.RecordingSpikesLamp = uilamp(app.UIFigure);
            app.EndSessionButton = uibutton(app.UIFigure, 'push');
            app.EndSessionButton.ButtonPushedFcn = createCallbackFcn(app, @EndSessionButtonPushed, true);
            app.EndSessionButton.Text = {'End'; 'Session'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TCApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end